classdef Design1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalTinkererV10UIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        OperationText              matlab.ui.control.Label
        Signal2DropDown            matlab.ui.control.DropDown
        Signal2DropDownLabel       matlab.ui.control.Label
        Signal1DropDown            matlab.ui.control.DropDown
        Signal1DropDownLabel       matlab.ui.control.Label
        SignalSelectDropDown       matlab.ui.control.DropDown
        SignalSelectDropDownLabel  matlab.ui.control.Label
        SignalNameEditField        matlab.ui.control.EditField
        SignalNameEditFieldLabel   matlab.ui.control.Label
        SamplesField               matlab.ui.control.NumericEditField
        SamplesEditFieldLabel      matlab.ui.control.Label
        RangeEditFieldLabel        matlab.ui.control.Label
        ModifySignalLabel          matlab.ui.control.Label
        TypeOperationDropDown      matlab.ui.control.DropDown
        function_label             matlab.ui.control.Label
        a_slider                   matlab.ui.control.Slider
        b_slider                   matlab.ui.control.Slider
        c_slider                   matlab.ui.control.Slider
        d_slider                   matlab.ui.control.Slider
        d_label                    matlab.ui.control.Label
        c_label                    matlab.ui.control.Label
        b_label                    matlab.ui.control.Label
        a_label                    matlab.ui.control.Label
        MinRangeField              matlab.ui.control.NumericEditField
        tLabel                     matlab.ui.control.Label
        MaxRangeField              matlab.ui.control.NumericEditField
        ConfirmButton              matlab.ui.control.Button
        TypeOperationLabel         matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        freqAxes                   matlab.ui.control.UIAxes
        timeAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Global parameters
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Signal Array counter
        i;
        signalValues = ["Sine","Square","Sawtooth","Triangle"];
        operationValues =  [
                "(+) Addition"
                "(-) Subtraction"
                "(×) Multiplication"
                "(*) Convolution"
                ];

        selectedOperation;

        SignalArray;
        
        % slider parameters
        a_param
        b_param
        c_param
        d_param
        e_param

        % sampling parameters
        Fs  % Sampling Rate
        T   % Sampling Period
        
        % Time domain
        min_t
        max_t
        
        t   % Time vector
        X   % Time domain signal vector

        % Frequency domain
        f   % frequency domain signal vector
        P1  % ??????????????????????????????
    end
    
    % Methods that aren't tied to an event
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Functions for updating signal
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update the a,b,c,d silder parameters
        function updateSignalPram(app)
            app.a_param = app.a_slider.Value;
            app.b_param = app.b_slider.Value;
            app.c_param = app.c_slider.Value;
            app.d_param = app.d_slider.Value;
        end

        % Updated Sampling Rate
        function calcSampling(app)
            app.Fs = app.SamplesField.Value;
            app.T = 1/app.Fs;
        end
        
        % Update Range of signal
        function calcSignalRange(app)
            app.min_t = app.MinRangeField.Value;
            app.max_t = app.MaxRangeField.Value;

            app.t = (app.min_t:app.max_t)*app.T;        % Time vector
        end

        % Calculate both Signal
        function calculateSignal(app)
            %Note: Using hertz here.
            if app.TypeOperationDropDown.Value == "Sine"
                app.X = app.a_param * sin(app.b_param * 2 * pi * app.t + app.c_param) + app.d_param;
            elseif app.TypeOperationDropDown.Value == "Square"
                app.X = app.a_param * square(app.b_param * 2 * pi * app.t) + app.c_param;
            elseif app.TypeOperationDropDown.Value == "Sawtooth"
                app.X = app.a_param * sawtooth(app.b_param * 2 * pi * app.t) + app.c_param;
            elseif app.TypeOperationDropDown.Value == "Triangle"
                app.X = app.a_param * sawtooth(app.b_param * 2 * pi * app.t, 0.5) + app.c_param;
            end
            Y = fft(app.X);
            P2 = abs(Y/app.max_t);
            app.P1 = P2(1:app.max_t/2+1);
            app.P1(2:end-1) = 2*app.P1(2:end-1);
            %Convert to dB
            app.P1 = 20 * log10(app.P1);
            app.f = app.Fs*(0:(app.max_t/2))/app.max_t;
        end

        % Rendering Signals
        function updateDisplay(app)
            cla(app.timeAxes);
            cla(app.freqAxes);
            hold(app.timeAxes,'on');
            hold(app.freqAxes,'on');

            if ~isempty(app.SignalArray)
                for j = 1:length(app.SignalArray)
                    plot(app.timeAxes,app.SignalArray(j).t,app.SignalArray(j).X);
                    plot(app.freqAxes, app.SignalArray(j).f, app.SignalArray(j).P1);
                end
            end
            % hold(app.timeAxes,'off');
            % hold(app.freqAxes,'off');
            grid(app.timeAxes, 'on');
            grid(app.freqAxes, 'on');
            xlabel(app.timeAxes, 'Time (s)');
            ylabel(app.timeAxes, 'Amplitude (V)');

            xlabel(app.freqAxes, 'Frequency (Hz)');
            ylabel(app.freqAxes, 'Amplitude (dB)');
            plot(app.timeAxes,app.t,app.X)
            plot(app.freqAxes, app.f, app.P1);
        end

        % Refresh All
        function RefreshEntireSignal(app)
           updateSignalPram(app)
           calcSampling(app)
           calcSignalRange(app)
           calculateSignal(app)
           updateDisplay(app)
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Confirm Signal
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function ConfirmSignal(app)
            % Saving Signal Parameters
            % app.SignalArray(end+1).Fs = app.Fs;

            
            %Are we in operations mode or are we in signal mode?
            if ~isSignalMode(app)
                %If this is an operation, calculate values.

                %Let's first find Signal1.
                for j = 1:length(app.SignalArray)
                    if strcmp(app.SignalArray(j).name, app.Signal1DropDown.Value)
                        signal1 = app.SignalArray(j);
                    end
                end

                %Let's first find Signal2.
                for j = 1:length(app.SignalArray)
                    if strcmp(app.SignalArray(j).name, app.Signal2DropDown.Value)
                        signal2 = app.SignalArray(j);
                    end
                end

                %Switch based on the operation we're applying.
                if app.selectedOperation == "(+) Addition"
                    app.X = signal1.X + signal2.X;
                elseif app.selectedOperation == "(-) Subtraction"
                    app.X = signal1.X - signal2.X;
                elseif app.selectedOperation == "(×) Multiplication"
                    app.X = signal1.X .* signal2.X;
                elseif app.selectedOperation == "(*) Convolution"
                    app.X = conv(signal1.X, signal2.X, 'same');
                end
            end
            app.SignalArray(app.i).Fs = app.Fs;
            app.SignalArray(app.i).T = app.T;

            app.SignalArray(app.i).t = app.t;
            app.SignalArray(app.i).X = app.X;          

            app.SignalArray(app.i).f = app.f;
            app.SignalArray(app.i).P1 = app.P1;

            app.SignalArray(app.i).name = app.SignalNameEditField.Value;
            app.SignalSelectDropDown.Items = [app.SignalSelectDropDown.Items, app.SignalArray(app.i).name];
            app.SignalSelectDropDown.Value = app.SignalArray(app.i).name;
            app.i = app.i + 1;

            %Update the name field for the next signal.
            tokenNames = regexp(app.SignalNameEditField.Value,".* (?<num>[0-9]+)",'names');
            %If there is an integer at the end of this signal name.
            if length(tokenNames) > 0
                signalNum = str2num(tokenNames.num);
                signalNum = signalNum + 1;

                app.SignalNameEditField.Value = sprintf("Signal %d", signalNum);
            else
                app.SignalNameEditField.Value = sprintf("%s %d", app.SignalNameEditField.Value, 1);
            end

            updateDisplay(app)
        end

        
        function configureUIDisplay(app, type)
            signalConfigElements = [
                app.a_slider
                app.a_label
                app.b_slider
                app.b_label
                app.c_slider
                app.c_label
                app.d_slider
                app.d_label
            ];
            signalOperationElements = [
                app.OperationText
                app.Signal1DropDown
                app.Signal1DropDownLabel
                app.Signal2DropDown
                app.Signal2DropDownLabel
            ];
            %If DISABLE ALL
            if type == 0
                app.function_label.Text = "";
                for elem = 1:length(signalConfigElements)
                    signalConfigElements(elem).Visible = false;
                end
                for elem = 1:length(signalOperationElements)
                    signalOperationElements(elem).Visible = false;
                end
            %If ONLY ENABLE function configuration
            elseif type == 1
                for elem = 1:length(signalConfigElements)
                    signalConfigElements(elem).Visible = true;
                end
                for elem = 1:length(signalOperationElements)
                    signalOperationElements(elem).Visible = false;
                end
            %If ONLY ENABLE operation configuration
            elseif type == 2
                app.function_label.Text = "Mathematical Operation";
                for elem = 1:length(signalConfigElements)
                    signalConfigElements(elem).Visible = false;
                end
                for elem = 1:length(signalOperationElements)
                    signalOperationElements(elem).Visible = true;
                end

                %Also update text in dropdown boxes.
                app.Signal1DropDown.Items = app.SignalSelectDropDown.Items;
                app.Signal2DropDown.Items = app.SignalSelectDropDown.Items;
            end
        end
        
        function inSignalMode = isSignalMode(app)
            if ismember(app.TypeOperationDropDown.Value, app.signalValues)
                inSignalMode = true;
            else
                inSignalMode = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.i = 1;
            
            RefreshEntireSignal(app)
            updateDisplay(app)
            configureUIDisplay(app, 1)
        end

        % Value changed function: TypeOperationDropDown
        function TypeOperationDropDownValueChanged(app, event)


            value = app.TypeOperationDropDown.Value;
            if ismember(value, app.signalValues)
                configureUIDisplay(app, 1)
            elseif ismember(value, app.operationValues)
                configureUIDisplay(app, 2)
            else
                configureUIDisplay(app, 0)
            end
            
            app.selectedOperation = 0;
            if value == "Sine"
                app.function_label.Text = "y = a * sin(b * t + c) + d";
            elseif value == "Square"
                app.function_label.Text = "y = a * square(b * t) + c ";
                app.d_slider.Visible = false;
                app.d_label.Visible = false;
            elseif value == "Sawtooth"
                app.function_label.Text = "y = a * sawtooth(b * t) + c ";
                app.d_slider.Visible = false;
                app.d_label.Visible = false;
            elseif value == "Triangle"
                app.function_label.Text = "y = a * sawtooth(b * t, 0.5) + c ";
                app.d_slider.Visible = false;
                app.d_label.Visible = false;
            elseif value == "(+) Addition"
                app.selectedOperation = value;
                app.OperationText.Text = "+";
            elseif value == "(-) Subtraction"
                app.selectedOperation = value;
                app.OperationText.Text = "-";
            elseif value == "(×) Multiplication"
                app.selectedOperation = value;
                app.OperationText.Text = "×";
            elseif value == "(*) Convolution"
                app.selectedOperation = value;
                app.OperationText.Text = "*";
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            ConfirmSignal(app);
        end

        % Callback function: MaxRangeField, MinRangeField, SamplesField, 
        % ...and 5 other components
        function SignalVariableChanged(app, event)
            RefreshEntireSignal(app);
        end

        % Value changed function: SignalSelectDropDown
        function SignalSelectDropDownValueChanged(app, event)
            value = app.SignalSelectDropDown.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SignalTinkererV10UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {535, 535};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {294, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SignalTinkererV10UIFigure = uifigure('Visible', 'off');
            app.SignalTinkererV10UIFigure.Name = 'Signal Tinkerer V1.0';
            app.SignalTinkererV10UIFigure.Icon = fullfile(pathToMLAPP, 'Icon.png');
            app.SignalTinkererV10UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SignalTinkererV10UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TypeOperationLabel = uilabel(app.LeftPanel);
            app.TypeOperationLabel.Text = 'Type / Operation';
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.MaxRangeField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxRangeField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.MaxRangeField.Value = 1500;
            app.tLabel = uilabel(app.LeftPanel);
            app.tLabel.Text = '< t <';
            app.MinRangeField = uieditfield(app.LeftPanel, 'numeric');
            app.MinRangeField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.a_label = uilabel(app.LeftPanel);
            app.a_label.Text = 'a';
            app.b_label = uilabel(app.LeftPanel);
            app.b_label.Text = 'b';
            app.c_label = uilabel(app.LeftPanel);
            app.c_label.Text = 'c';
            app.d_label = uilabel(app.LeftPanel);
            app.d_label.Text = 'd';
            app.d_slider = uislider(app.LeftPanel);
            app.d_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.c_slider = uislider(app.LeftPanel);
            app.c_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.b_slider = uislider(app.LeftPanel);
            app.b_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.b_slider.Value = 10;
            app.a_slider = uislider(app.LeftPanel);
            app.a_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.a_slider.ValueChangingFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.a_slider.Value = 1;
            app.function_label = uilabel(app.LeftPanel);
            app.function_label.Text = 'y = a*sin(b*x + c) + d';
            app.TypeOperationDropDown = uidropdown(app.LeftPanel);
            app.TypeOperationDropDown.Items = {'Sine', 'Square', 'Sawtooth', 'Triangle', '(+) Addition', '(-) Subtraction', '(×) Multiplication', '(*) Convolution'};
            app.TypeOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeOperationDropDownValueChanged, true);
            app.TypeOperationDropDown.Value = 'Sine';
            app.ModifySignalLabel = uilabel(app.LeftPanel);
            app.ModifySignalLabel.Text = 'Modify Signal';
            app.RangeEditFieldLabel = uilabel(app.LeftPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.SamplesEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesField = uieditfield(app.LeftPanel, 'numeric');
            app.SamplesField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.SamplesField.Value = 3000;
            app.SignalNameEditFieldLabel = uilabel(app.LeftPanel);
            app.SignalNameEditFieldLabel.Text = 'Signal Name';
            app.SignalNameEditField = uieditfield(app.LeftPanel, 'text');
            app.SignalNameEditField.Value = 'Signal 1';
            app.SignalSelectDropDownLabel = uilabel(app.LeftPanel);
            app.SignalSelectDropDownLabel.Text = 'Selected:';
            app.SignalSelectDropDown = uidropdown(app.LeftPanel);
            app.SignalSelectDropDown.Items = {};
            app.SignalSelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalSelectDropDownValueChanged, true);
            app.SignalSelectDropDown.Value = {};
            app.Signal1DropDownLabel = uilabel(app.LeftPanel);
            app.Signal1DropDownLabel.Text = 'Signal 1';
            app.Signal1DropDown = uidropdown(app.LeftPanel);
            app.Signal2DropDownLabel = uilabel(app.LeftPanel);
            app.Signal2DropDownLabel.Text = 'Signal 2';
            app.Signal2DropDown = uidropdown(app.LeftPanel);
            app.OperationText = uilabel(app.LeftPanel);
            app.OperationText.Text = '+';
            app.RightPanel = uipanel(app.GridLayout);
            app.timeAxes = uiaxes(app.RightPanel);
            title(app.timeAxes, 'Time Domain')
            xlabel(app.timeAxes, 'X')
            ylabel(app.timeAxes, 'Y')
            zlabel(app.timeAxes, 'Z')
            app.freqAxes = uiaxes(app.RightPanel);
            title(app.freqAxes, 'Frequency Domain')
            xlabel(app.freqAxes, 'X')
            ylabel(app.freqAxes, 'Y')
            zlabel(app.freqAxes, 'Z')
            % Show the figure after all components are created
            app.SignalTinkererV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Design1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalTinkererV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalTinkererV10UIFigure)
        end
    end
end