classdef SignalProcessingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalTinkererV10UIFigure  matlab.ui.Figure
        Toolbar                    matlab.ui.container.Toolbar
        ToggleCreateSignal         matlab.ui.container.toolbar.PushTool
        ToggleSavedSignal          matlab.ui.container.toolbar.PushTool
        ClearSignals               matlab.ui.container.toolbar.PushTool
        BasePanel                  matlab.ui.container.Panel
        ModifySignalsPanel         matlab.ui.container.Panel
        SignalListBox              matlab.ui.control.ListBox
        SavedSignalsLabel          matlab.ui.control.Label
        UIPanel                    matlab.ui.container.Panel
        RangeEditFieldLabel        matlab.ui.control.Label
        MinRangeField              matlab.ui.control.NumericEditField
        Signal2DropDown            matlab.ui.control.DropDown
        Signal2DropDownLabel       matlab.ui.control.Label
        Signal1DropDown            matlab.ui.control.DropDown
        Signal1DropDownLabel       matlab.ui.control.Label
        SignalSelectDropDown       matlab.ui.control.DropDown
        SignalSelectDropDownLabel  matlab.ui.control.Label
        SignalNameEditField        matlab.ui.control.EditField
        SignalNameEditFieldLabel   matlab.ui.control.Label
        SamplesField               matlab.ui.control.NumericEditField
        SamplesEditFieldLabel      matlab.ui.control.Label
        d_slider                   matlab.ui.control.Slider
        d_label                    matlab.ui.control.Label
        c_slider                   matlab.ui.control.Slider
        c_label                    matlab.ui.control.Label
        b_slider                   matlab.ui.control.Slider
        b_label                    matlab.ui.control.Label
        a_slider                   matlab.ui.control.Slider
        a_label                    matlab.ui.control.Label
        TypeOperationDropDown      matlab.ui.control.DropDown
        TypeOperationLabel         matlab.ui.control.Label
        tLabel                     matlab.ui.control.Label
        MaxRangeField              matlab.ui.control.NumericEditField
        OperationText              matlab.ui.control.Label
        CreateSignalLabel          matlab.ui.control.Label
        function_label             matlab.ui.control.Label
        ConfirmButton              matlab.ui.control.Button
        freqAxes                   matlab.ui.control.UIAxes
        timeAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Global parameters
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Signal Array counter
        i;
        signalValues = ["Sine","Square","Sawtooth","Triangle"];
        operationValues =  [
                "(+) Addition"
                "(-) Subtraction"
                "(×) Multiplication"
                "(*) Convolution"
                ];

        selectedOperation; % Current Mathematical Operation

        SignalArray; % Array of saved signals
        
        % slider parameters
        a_param
        b_param
        c_param
        d_param
        e_param

        % sampling parameters
        Fs  % Sampling Rate
        T   % Sampling Period
        
        % Time domain
        min_t
        max_t
        
        t   % Time vector
        X   % Time domain signal vector

        % Frequency domain
        f   % frequency domain signal vector
        P1  % ??????????????????????????????
    end
    
    % Methods that aren't tied to an event
    methods (Access = private)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Functions for updating signal
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Update the a,b,c,d silder parameters
        function updateSignalPram(app)
            app.a_param = app.a_slider.Value;
            app.b_param = app.b_slider.Value;
            app.c_param = app.c_slider.Value;
            app.d_param = app.d_slider.Value;
        end

        % Updated Sampling Rate
        function calcSampling(app)
            app.Fs = app.SamplesField.Value;
            app.T = 1/app.Fs;
        end
        
        % Update Range of signal
        function calcSignalRange(app)
            app.min_t = app.MinRangeField.Value;
            app.max_t = app.MaxRangeField.Value;

            app.t = (app.min_t:app.max_t)*app.T;        % Time vector
        end

        % Calculate both Signal
        function calculateSignal(app)
            %Note: Using hertz here.
            if app.TypeOperationDropDown.Value == "Sine"
                app.X = app.a_param * sin(app.b_param * 2 * pi * app.t + app.c_param) + app.d_param;
            elseif app.TypeOperationDropDown.Value == "Square"
                app.X = app.a_param * square(app.b_param * 2 * pi * app.t) + app.c_param;
            elseif app.TypeOperationDropDown.Value == "Sawtooth"
                app.X = app.a_param * sawtooth(app.b_param * 2 * pi * app.t) + app.c_param;
            elseif app.TypeOperationDropDown.Value == "Triangle"
                app.X = app.a_param * sawtooth(app.b_param * 2 * pi * app.t, 0.5) + app.c_param;
            end
            Y = fft(app.X);
            P2 = abs(Y/app.max_t);
            app.P1 = P2(1:app.max_t/2+1);
            app.P1(2:end-1) = 2*app.P1(2:end-1);
            %Convert to dB
            app.P1 = 20 * log10(app.P1);
            app.f = app.Fs*(0:(app.max_t/2))/app.max_t;
        end

        % Rendering Signals
        function updateDisplay(app)
            cla(app.timeAxes);
            cla(app.freqAxes);
            hold(app.timeAxes,'on');
            hold(app.freqAxes,'on');

            if ~isempty(app.SignalArray)
                for j = 1:length(app.SignalArray)
                    plot(app.timeAxes,app.SignalArray(j).t,app.SignalArray(j).X);
                    plot(app.freqAxes, app.SignalArray(j).f, app.SignalArray(j).P1);
                end
            end
            % hold(app.timeAxes,'off');
            % hold(app.freqAxes,'off');
            grid(app.timeAxes, 'on');
            grid(app.freqAxes, 'on');
            xlabel(app.timeAxes, 'Time (s)');
            ylabel(app.timeAxes, 'Amplitude (V)');

            xlabel(app.freqAxes, 'Frequency (Hz)');
            ylabel(app.freqAxes, 'Amplitude (dB)');
            plot(app.timeAxes,app.t,app.X)
            plot(app.freqAxes, app.f, app.P1);
        end

        % Refresh All
        function RefreshEntireSignal(app)
           updateSignalPram(app)
           calcSampling(app)
           calcSignalRange(app)
           calculateSignal(app)
           updateDisplay(app)
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Save Current Signal
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function ConfirmSignal(app)
            % Saving Signal Parameters
            % app.SignalArray(end+1).Fs = app.Fs;

            
            %Are we in operations mode or are we in signal mode?
            if ~isSignalMode(app)
                %If this is an operation, calculate values.

                %Let's first find Signal1.
                for j = 1:length(app.SignalArray)
                    if strcmp(app.SignalArray(j).name, app.Signal1DropDown.Value)
                        signal1 = app.SignalArray(j);
                    end
                end

                %Let's first find Signal2.
                for j = 1:length(app.SignalArray)
                    if strcmp(app.SignalArray(j).name, app.Signal2DropDown.Value)
                        signal2 = app.SignalArray(j);
                    end
                end

                %Switch based on the operation we're applying.
                if app.selectedOperation == "(+) Addition"
                    app.X = signal1.X + signal2.X;
                elseif app.selectedOperation == "(-) Subtraction"
                    app.X = signal1.X - signal2.X;
                elseif app.selectedOperation == "(×) Multiplication"
                    app.X = signal1.X .* signal2.X;
                elseif app.selectedOperation == "(*) Convolution"
                    app.X = conv(signal1.X, signal2.X, 'same');
                end
            end

            % Writing current data to signal array
            app.SignalArray(app.i).Fs = app.Fs;
            app.SignalArray(app.i).T = app.T;

            app.SignalArray(app.i).t = app.t;
            app.SignalArray(app.i).X = app.X;          

            app.SignalArray(app.i).f = app.f;
            app.SignalArray(app.i).P1 = app.P1;

            app.SignalArray(app.i).name = app.SignalNameEditField.Value;
            app.SignalSelectDropDown.Items = [app.SignalSelectDropDown.Items, app.SignalArray(app.i).name];
            app.SignalSelectDropDown.Value = app.SignalArray(app.i).name;
            app.i = app.i + 1;

            %Update the name field for the next signal.
            tokenNames = regexp(app.SignalNameEditField.Value,".* (?<num>[0-9]+)",'names');
            
            %If there is an integer at the end of this signal name.
            if length(tokenNames) > 0
                
                signalNum = str2num(tokenNames.num);
                SignalListItemValue = [app.SignalListBox.Items sprintf("Signal %d\n", signalNum)];
                
                % if ~isempty(app.SignalListBox.Items) % wierd thing to avoid it not adding to box if the box is empty
                %     app.SignalListBox.Items = SignalListItemValue;
                %     app.SignalListBox.ItemsData = SignalListItemValue;

                % else
                    app.SignalListBox.Items = SignalListItemValue;
                    app.SignalListBox.ItemsData = SignalListItemValue;

                % end
                signalNum = signalNum + 1;

                app.SignalNameEditField.Value = sprintf("Signal %d", signalNum);

                % Saving Signal to ModifySignals Pannel
                
            else
                if ~isempty(app.SignalListBox.Items) % wierd thing to avoid it not adding to box if the box is empty
                    
                    app.SignalListBox.Items = [app.SignalListBox.Items sprintf("%s %d\n", app.SignalNameEditField.Value, 1)];
                        
                    app.SignalListBox.ItemsData = [app.SignalListBox.Items sprintf("%s %d\n", app.SignalNameEditField.Value, 1)];

                else
                    app.SignalListBox.Items = sprintf("%s %d\n", app.SignalNameEditField.Value, 1);
                    app.SignalListBox.ItemsData = [app.SignalListBox.Items sprintf("%s %d\n", app.SignalNameEditField.Value, 1)];
                end
                

                % Saving Signal to ModifySignals Pannel
                app.SignalNameEditField.Value = sprintf("%s %d", app.SignalNameEditField.Value, 1);

                                
            end
            updateDisplay(app)
        end

        
        function configureUIDisplay(app, type)
            signalConfigElements = [
                app.a_slider
                app.a_label
                app.b_slider
                app.b_label
                app.c_slider
                app.c_label
                app.d_slider
                app.d_label
            ];
            signalOperationElements = [
                app.OperationText
                app.Signal1DropDown
                app.Signal1DropDownLabel
                app.Signal2DropDown
                app.Signal2DropDownLabel
            ];
            %If DISABLE ALL
            if type == 0
                app.function_label.Text = "";
                for elem = 1:length(signalConfigElements)
                    signalConfigElements(elem).Visible = false;
                end
                for elem = 1:length(signalOperationElements)
                    signalOperationElements(elem).Visible = false;
                end
            %If ONLY ENABLE function configuration
            elseif type == 1
                for elem = 1:length(signalConfigElements)
                    signalConfigElements(elem).Visible = true;
                end
                for elem = 1:length(signalOperationElements)
                    signalOperationElements(elem).Visible = false;
                end
            %If ONLY ENABLE operation configuration
            elseif type == 2
                app.function_label.Text = "Mathematical Operation";
                for elem = 1:length(signalConfigElements)
                    signalConfigElements(elem).Visible = false;
                end
                for elem = 1:length(signalOperationElements)
                    signalOperationElements(elem).Visible = true;
                end

                %Also update text in dropdown boxes.
                app.Signal1DropDown.Items = app.SignalSelectDropDown.Items;
                app.Signal2DropDown.Items = app.SignalSelectDropDown.Items;
            end
        end
        
        function inSignalMode = isSignalMode(app)
            if ismember(app.TypeOperationDropDown.Value, app.signalValues)
                inSignalMode = true;
            else
                inSignalMode = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.i = 1;
            % app.UIPanel.OuterPosition = [10,17,303,508];
            
            RefreshEntireSignal(app)
            updateDisplay(app)
            configureUIDisplay(app, 1)
        end

        % Value changed function: TypeOperationDropDown
        function TypeOperationDropDownValueChanged(app, event)


            value = app.TypeOperationDropDown.Value;
            if ismember(value, app.signalValues)
                configureUIDisplay(app, 1)
            elseif ismember(value, app.operationValues)
                configureUIDisplay(app, 2)
            else
                configureUIDisplay(app, 0)
            end
            
            app.selectedOperation = 0;
            if value == "Sine"
                app.function_label.Text = "y = a * sin(b * t + c) + d";
            elseif value == "Square"
                app.function_label.Text = "y = a * square(b * t) + c ";
                app.d_slider.Visible = false;
                app.d_label.Visible = false;
            elseif value == "Sawtooth"
                app.function_label.Text = "y = a * sawtooth(b * t) + c ";
                app.d_slider.Visible = false;
                app.d_label.Visible = false;
            elseif value == "Triangle"
                app.function_label.Text = "y = a * sawtooth(b * t, 0.5) + c ";
                app.d_slider.Visible = false;
                app.d_label.Visible = false;
            elseif value == "(+) Addition"
                app.selectedOperation = value;
                app.OperationText.Text = "+";
            elseif value == "(-) Subtraction"
                app.selectedOperation = value;
                app.OperationText.Text = "-";
            elseif value == "(×) Multiplication"
                app.selectedOperation = value;
                app.OperationText.Text = "×";
            elseif value == "(*) Convolution"
                app.selectedOperation = value;
                app.OperationText.Text = "*";
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            ConfirmSignal(app);
        end

        % Callback function: MaxRangeField, MinRangeField, SamplesField, 
        % ...and 5 other components
        function SignalVariableChanged(app, event)
            RefreshEntireSignal(app);
        end

        % Callback function: ToggleCreateSignal
        function TogglePannelVis(app, event)
            if app.UIPanel.Visible == "on"
                % Fiding dimensions of Graph figures
                figureWidth = app.BasePanel.Position(3) - 0.05*app.BasePanel.Position(3);
                figureHeight = (app.BasePanel.Position(4) - 0.05*app.BasePanel.Position(4))/2;
                
                % Finding position of Graph figures
                figurePosX = 0.05*app.BasePanel.Position(3)/2;
                figurePosYtime = 0.05*app.BasePanel.Position(4)/2;
                figurePosYfreq = app.BasePanel.Position(4)/2 + 0.05*app.BasePanel.Position(4)/2;
                
                % Repositioning Graph
                app.UIPanel.Visible = "off";
                app.timeAxes.Position = [figurePosX,figurePosYtime,figureWidth,figureHeight];
                app.freqAxes.Position = [figurePosX,figurePosYfreq,figureWidth, figureHeight];
                

            elseif app.UIPanel.Visible == "off"
                
                % Fiding dimensions of Graph figures
                figureWidth = app.BasePanel.Position(3) - 0.05*app.BasePanel.Position(3) - app.UIPanel.Position(3);
                figureHeight = (app.BasePanel.Position(4) - 0.05*app.BasePanel.Position(4))/2;
                
                % Finding position of Graph figures
                figurePosX = 0.05*app.BasePanel.Position(3)/2 + app.UIPanel.Position(3);
                figurePosYtime = 0.05*app.BasePanel.Position(4)/2;
                figurePosYfreq = app.BasePanel.Position(4)/2 + 0.05*app.BasePanel.Position(4)/2;

                % Repositioning Graph
                app.timeAxes.Position = [figurePosX,figurePosYtime,figureWidth,figureHeight];
                app.freqAxes.Position = [figurePosX,figurePosYfreq,figureWidth,figureHeight];

                app.UIPanel.Visible = "on";
            end 
        end

        % Callback function: ToggleSavedSignal
        function ToggleSavedSignals(app, event)
            if app.ModifySignalsPanel.Visible == "on"
                app.ModifySignalsPanel.Visible = "off";
            elseif app.ModifySignalsPanel.Visible == "off"
                app.ModifySignalsPanel.Visible = "on";
            end
        end

        % Callback function: ClearSignals
        function DeleteSaved(app, event)
            app.SignalArray(:) = [];
            app.SignalListBox.Items(:) = [];
            app.SignalSelectDropDown.Items(:) = [];
            app.SignalNameEditField.Value="Signal 1";
            hold(app.timeAxes,'off');
            hold(app.freqAxes,'off');
            cla(app.timeAxes);
            cla(app.freqAxes);
            app.i=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SignalTinkererV10UIFigure = uifigure('Visible', 'off');
            app.SignalTinkererV10UIFigure.Name = 'Signal Tinkerer V1.0';
            app.SignalTinkererV10UIFigure.Icon = fullfile(pathToMLAPP, 'Design1_resources', 'Icon.png');
            app.Toolbar = uitoolbar(app.SignalTinkererV10UIFigure);
            app.ToggleCreateSignal = uipushtool(app.Toolbar);
            app.ToggleCreateSignal.Tooltip = {'Hide Modify Signals panel'};
            app.ToggleCreateSignal.ClickedCallback = createCallbackFcn(app, @TogglePannelVis, true);
            app.ToggleCreateSignal.Icon = fullfile(pathToMLAPP, 'Design1_resources', 'OpenClose.png');
            app.ToggleSavedSignal = uipushtool(app.Toolbar);
            app.ToggleSavedSignal.Tooltip = {'Hide Saved Signals panel'};
            app.ToggleSavedSignal.ClickedCallback = createCallbackFcn(app, @ToggleSavedSignals, true);
            app.ToggleSavedSignal.Icon = fullfile(pathToMLAPP, 'Design1_resources', 'OpenClose.png');
            app.ToggleSavedSignal.Separator = 'on';
            app.ClearSignals = uipushtool(app.Toolbar);
            app.ClearSignals.Tooltip = {'Clear all saved signals'};
            app.ClearSignals.ClickedCallback = createCallbackFcn(app, @DeleteSaved, true);
            app.ClearSignals.Icon = fullfile(pathToMLAPP, 'Design1_resources', 'trash-bin-icon-line-vector.jpg');
            app.ClearSignals.Separator = 'on';
            app.BasePanel = uipanel(app.SignalTinkererV10UIFigure);
            app.timeAxes = uiaxes(app.BasePanel);
            title(app.timeAxes, 'Time Domain')
            xlabel(app.timeAxes, 'X')
            ylabel(app.timeAxes, 'Y')
            zlabel(app.timeAxes, 'Z')
            app.freqAxes = uiaxes(app.BasePanel);
            title(app.freqAxes, 'Frequency Domain')
            xlabel(app.freqAxes, 'X')
            ylabel(app.freqAxes, 'Y')
            zlabel(app.freqAxes, 'Z')
            app.UIPanel = uipanel(app.BasePanel);
            app.ConfirmButton = uibutton(app.UIPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.function_label = uilabel(app.UIPanel);
            app.function_label.Text = 'y = a*sin(b*x + c) + d';
            app.CreateSignalLabel = uilabel(app.UIPanel);
            app.CreateSignalLabel.Text = 'Create Signal';
            app.OperationText = uilabel(app.UIPanel);
            app.OperationText.Text = '+';
            app.MaxRangeField = uieditfield(app.UIPanel, 'numeric');
            app.MaxRangeField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.MaxRangeField.Value = 1500;
            app.tLabel = uilabel(app.UIPanel);
            app.tLabel.Text = '< t <';
            app.TypeOperationLabel = uilabel(app.UIPanel);
            app.TypeOperationLabel.Text = 'Type / Operation';
            app.TypeOperationDropDown = uidropdown(app.UIPanel);
            app.TypeOperationDropDown.Items = {'Sine', 'Square', 'Sawtooth', 'Triangle', '(+) Addition', '(-) Subtraction', '(×) Multiplication', '(*) Convolution'};
            app.TypeOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeOperationDropDownValueChanged, true);
            app.TypeOperationDropDown.Value = 'Sine';
            app.a_label = uilabel(app.UIPanel);
            app.a_label.Text = 'a';
            app.a_slider = uislider(app.UIPanel);
            app.a_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.a_slider.ValueChangingFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.a_slider.Value = 1;
            app.b_label = uilabel(app.UIPanel);
            app.b_label.Text = 'b';
            app.b_slider = uislider(app.UIPanel);
            app.b_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.b_slider.Value = 10;
            app.c_label = uilabel(app.UIPanel);
            app.c_label.Text = 'c';
            app.c_slider = uislider(app.UIPanel);
            app.c_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.d_label = uilabel(app.UIPanel);
            app.d_label.Text = 'd';
            app.d_slider = uislider(app.UIPanel);
            app.d_slider.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.SamplesEditFieldLabel = uilabel(app.UIPanel);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesField = uieditfield(app.UIPanel, 'numeric');
            app.SamplesField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.SamplesField.Value = 3000;
            app.SignalNameEditFieldLabel = uilabel(app.UIPanel);
            app.SignalNameEditFieldLabel.Text = 'Signal Name';
            app.SignalNameEditField = uieditfield(app.UIPanel, 'text');
            app.SignalNameEditField.Value = 'Signal 1';
            app.SignalSelectDropDownLabel = uilabel(app.UIPanel);
            app.SignalSelectDropDownLabel.Text = 'Selected:';
            app.SignalSelectDropDown = uidropdown(app.UIPanel);
            app.SignalSelectDropDown.Items = {};
            app.SignalSelectDropDown.Value = {};
            app.Signal1DropDownLabel = uilabel(app.UIPanel);
            app.Signal1DropDownLabel.Text = 'Signal 1';
            app.Signal1DropDown = uidropdown(app.UIPanel);
            app.Signal2DropDownLabel = uilabel(app.UIPanel);
            app.Signal2DropDownLabel.Text = 'Signal 2';
            app.Signal2DropDown = uidropdown(app.UIPanel);
            app.MinRangeField = uieditfield(app.UIPanel, 'numeric');
            app.MinRangeField.ValueChangedFcn = createCallbackFcn(app, @SignalVariableChanged, true);
            app.RangeEditFieldLabel = uilabel(app.UIPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.ModifySignalsPanel = uipanel(app.BasePanel);
            app.SavedSignalsLabel = uilabel(app.ModifySignalsPanel);
            app.SavedSignalsLabel.Text = 'Saved Signals';
            app.SignalListBox = uilistbox(app.ModifySignalsPanel);
            app.SignalListBox.Items = {};
            app.SignalListBox.Value = {};
            % Show the figure after all components are created
            app.SignalTinkererV10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcessingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalTinkererV10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalTinkererV10UIFigure)
        end
    end
end