classdef mainWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        testTextArea       matlab.ui.control.TextArea
        testTextAreaLabel  matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        DropDownLabel      matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: DropDown, UIAxes
        function DropDownValueChanged(app, event)
            info = app.DropDown.Value
            app.testTextArea.Value= info;
            if info == "Addition"
                app.testTextArea.Value= info;
            elseif info == "Subtraction"
                app.testTextArea.Value= "world";
            elseif info == "Convolution"
                app.testTextArea.Value= "hello";
            else
                app.testTextArea.Value= "!!!!!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Addition', 'Subtraction', 'Convolution', 'Correlation'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Addition';
            app.testTextAreaLabel = uilabel(app.UIFigure);
            app.testTextAreaLabel.Text = 'test';
            app.testTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end